classdef Signal_box_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        NoiseKnobLabel             matlab.ui.control.Label
        Noise                      matlab.ui.control.DiscreteKnob
        Label                      matlab.ui.control.Label
        PeriodKnobLabel            matlab.ui.control.Label
        Period                     matlab.ui.control.Knob
        DominSwitchLabel           matlab.ui.control.Label
        Domin                      matlab.ui.control.Switch
        LampLabel                  matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        Panel                      matlab.ui.container.Panel
        Button                     matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        AmpEditFieldLabel          matlab.ui.control.Label
        Amp                        matlab.ui.control.NumericEditField
        SignalSelectDropDownLabel  matlab.ui.control.Label
        SignalSelect               matlab.ui.control.DropDown
        MulButton                  matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        FilterLabel                matlab.ui.control.Label
        Filter                     matlab.ui.control.Knob
    end


    properties (Access = private)
        autoupdate % Description
    end

    methods (Access = private)
        function updatePlot(app)
            results = generateSignal(app);
            plot(app.UIAxes, results(1,:), results(2,:));
        end
        
        function results = generateSignal(app)
            signal = app.SignalSelect.Value;
            noise = str2double(app.Noise.Value);
            amp = app.Amp.Value;
            filter = app.Filter.Value;
            domin = app.Domin;
            f = app.Period.Value;
            t = 0:0.001:1;
            switch signal
                case 'None'
                    x = zeros(length(t));
                case 'Square'
                    x = square(2*pi*f*t,50);
                case 'Triangular'
                    x = sawtooth(2*pi*f*t,0.5);
                case 'Sine'
                    x = sin(2*pi*f*t);
                otherwise
                    x = zeros(length(t));
            end
            x = x * amp;
            x = awgn(x,noise,'measured');
            
            if(strcmp(domin,'Freq'))
                NFET = 
            end
            
            
%             wp=2*pi*2500;ws=2*pi*3500;Rp=1;As=30;
%             [N,wc]=buttord(wp,ws,Rp,As,'s')%计算率波器的阶数和3dB截止频率
%             [B,A]=butter(N,wc,'s');%计算滤波器系统函数分子分母多项式
%             fk=0:800/512:8000;wk=2*pi*fk;
%             Hk=freqs(B,A,wk);
%             figure
%             plot(fk/1000,20*log10(abs(Hk)));
%             grid on,xlabel('频率（kHz）'),ylabel('幅度（dB）')
%             title('巴特沃斯模拟滤波器')
%                        
            results = [t; x];
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: SignalSelect
        function SignalSelectValueChanged(app, event)
            value = app.SignalSelect.Value;
            updatePlot(app);
        end

        % Value changing function: Period
        function PeriodValueChanging(app, event)
            changingValue = event.Value;
            updatePlot(app);
        end

        % Value changed function: Noise
        function NoiseValueChanged(app, event)
            value = app.Noise.Value;
            updatePlot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal Display')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NoiseKnobLabel = uilabel(app.UIFigure);
            app.NoiseKnobLabel.Text = 'Noise';
            app.Noise = uiknob(app.UIFigure, 'discrete');
            app.Noise.Items = {'100', '50', '20', '10', '1'};
            app.Noise.ValueChangedFcn = createCallbackFcn(app, @NoiseValueChanged, true);
            app.Noise.Value = '100';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '数字信号处理大作业';
            app.PeriodKnobLabel = uilabel(app.UIFigure);
            app.PeriodKnobLabel.Text = 'Period';
            app.Period = uiknob(app.UIFigure, 'continuous');
            app.Period.Limits = [1 10];
            app.Period.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Period.ValueChangingFcn = createCallbackFcn(app, @PeriodValueChanging, true);
            app.Period.Value = 1;
            app.DominSwitchLabel = uilabel(app.UIFigure);
            app.DominSwitchLabel.Text = 'Domin';
            app.Domin = uiswitch(app.UIFigure, 'slider');
            app.Domin.Items = {'Time', 'Freq'};
            app.Domin.Value = 'Time';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.Text = '-';
            app.AmpEditFieldLabel = uilabel(app.Panel);
            app.AmpEditFieldLabel.Text = 'Amp';
            app.Amp = uieditfield(app.Panel, 'numeric');
            app.Amp.Value = 1;
            app.SignalSelectDropDownLabel = uilabel(app.Panel);
            app.SignalSelectDropDownLabel.Text = 'Signal Select';
            app.SignalSelect = uidropdown(app.Panel);
            app.SignalSelect.Items = {'None', 'Square', 'Triangular', 'Sine'};
            app.SignalSelect.ValueChangedFcn = createCallbackFcn(app, @SignalSelectValueChanged, true);
            app.SignalSelect.Value = 'None';
            app.MulButton = uibutton(app.Panel, 'push');
            app.MulButton.Text = 'Mul';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Signal List'};
            app.UITable.RowName = {};
            app.FilterLabel = uilabel(app.UIFigure);
            app.FilterLabel.Text = 'Filter';
            app.Filter = uiknob(app.UIFigure, 'continuous');
            app.Filter.Limits = [1 10];
            app.Filter.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Filter.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Signal_box_v3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end